classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        MobileAppVisualizerPanel  matlab.ui.container.Panel
        FrecuenciaButtonGroup     matlab.ui.container.ButtonGroup
        AnualButton               matlab.ui.control.RadioButton
        MensualButton             matlab.ui.control.RadioButton
        DiariaButton              matlab.ui.control.RadioButton
        StoreSOButtonGroup        matlab.ui.container.ButtonGroup
        AppStoreiOSButton         matlab.ui.control.RadioButton
        GooglePlayAndroidButton   matlab.ui.control.RadioButton
        PeriodoSlider             matlab.ui.control.Slider
        PeriodoSliderLabel        matlab.ui.control.Label
        PaisListBox               matlab.ui.control.ListBox
        PasListBoxLabel           matlab.ui.control.Label
        ContinenteListBox         matlab.ui.control.ListBox
        ContinenteListBoxLabel    matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        isDataLoaded logical = 0;
        objDataset; % Dataset object
        datatable; % A variable to access data because it's no possible to do so with function getData()
        continents;
        countries;
    end
    
    methods (Access = private)
        
        function cargarDatos(app)
            if app.isDataLoaded == 0
                app.objDataset = MobileAppDataset();
                app.datatable = app.objDataset.getCountPerMonthTable();
                app.continents = app.objDataset.getContinents();
                app.countries = app.objDataset.getCountries();

                app.ContinenteListBox.Items = app.continents{:,1};
                app.ContinenteListBox.ItemsData = 1:length(app.continents{:,1});
                app.ContinenteListBox.Value = 1;
                app.PaisListBox.Items = app.countries{app.ContinenteListBox.Value}{:,1}{:,1};
                app.PaisListBox.ItemsData = 1:length(app.countries{app.ContinenteListBox.Value}{:,1}{:,1});
                app.PaisListBox.Value = 1;
                
                [releaseDate, cant] = app.objDataset.getCountPerMonth();
                plot(app.UIAxes,releaseDate, cant);
                app.isDataLoaded = 1;
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cargarDatos();
            disp(horzcat('DataLoaded: ',num2str(app.isDataLoaded)))
        end

        % Value changed function: ContinenteListBox
        function ContinenteListBoxValueChanged(app, event)
            app.PaisListBox.Items = app.countries{app.ContinenteListBox.Value}{:,1}{:,1};
            app.PaisListBox.ItemsData = 1:length(app.countries{app.ContinenteListBox.Value}{:,1}{:,1});
            app.PaisListBox.Value = 1;
        end

        % Value changed function: PaisListBox
        function PaisListBoxValueChanged(app, event)
            value = app.PaisListBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MobileAppVisualizerPanel = uipanel(app.UIFigure);
            app.MobileAppVisualizerPanel.Title = 'MobileAppVisualizer';
            app.UIAxes = uiaxes(app.MobileAppVisualizerPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ContinenteListBoxLabel = uilabel(app.MobileAppVisualizerPanel);
            app.ContinenteListBoxLabel.Text = 'Continente';
            app.ContinenteListBox = uilistbox(app.MobileAppVisualizerPanel);
            app.ContinenteListBox.Items = {};
            app.ContinenteListBox.ValueChangedFcn = createCallbackFcn(app, @ContinenteListBoxValueChanged, true);
            app.ContinenteListBox.Value = {};
            app.PasListBoxLabel = uilabel(app.MobileAppVisualizerPanel);
            app.PasListBoxLabel.Text = 'País';
            app.PaisListBox = uilistbox(app.MobileAppVisualizerPanel);
            app.PaisListBox.Items = {};
            app.PaisListBox.ValueChangedFcn = createCallbackFcn(app, @PaisListBoxValueChanged, true);
            app.PaisListBox.Value = {};
            app.PeriodoSliderLabel = uilabel(app.MobileAppVisualizerPanel);
            app.PeriodoSliderLabel.Text = 'Periodo';
            app.PeriodoSlider = uislider(app.MobileAppVisualizerPanel);
            app.StoreSOButtonGroup = uibuttongroup(app.MobileAppVisualizerPanel);
            app.StoreSOButtonGroup.Title = 'Store - SO';
            app.GooglePlayAndroidButton = uiradiobutton(app.StoreSOButtonGroup);
            app.GooglePlayAndroidButton.Text = 'GooglePlay (Android)';
            app.GooglePlayAndroidButton.Value = true;
            app.AppStoreiOSButton = uiradiobutton(app.StoreSOButtonGroup);
            app.AppStoreiOSButton.Text = 'AppStore (iOS)';
            app.FrecuenciaButtonGroup = uibuttongroup(app.MobileAppVisualizerPanel);
            app.FrecuenciaButtonGroup.Title = 'Frecuencia';
            app.DiariaButton = uiradiobutton(app.FrecuenciaButtonGroup);
            app.DiariaButton.Text = 'Diaria';
            app.DiariaButton.Value = true;
            app.MensualButton = uiradiobutton(app.FrecuenciaButtonGroup);
            app.MensualButton.Text = 'Mensual';
            app.AnualButton = uiradiobutton(app.FrecuenciaButtonGroup);
            app.AnualButton.Text = 'Anual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end